classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DataManegementPanel             matlab.ui.container.Panel
        SaveName                        matlab.ui.control.EditField
        modelSave                       matlab.ui.control.Button
        filenameField                   matlab.ui.control.EditField
        ImportButton                    matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        PostureTab                      matlab.ui.container.Tab
        AutoOrManual                    matlab.ui.container.ButtonGroup
        Manual                          matlab.ui.control.RadioButton
        Auto                            matlab.ui.control.RadioButton
        ManualPosturePanel              matlab.ui.container.Panel
        OKButton_2                      matlab.ui.control.Button
        Switch                          matlab.ui.control.Switch
        Knob                            matlab.ui.control.Knob
        Button_11                       matlab.ui.control.Button
        ButtonGroup_3                   matlab.ui.container.ButtonGroup
        Button_10                       matlab.ui.control.RadioButton
        PCAButton                       matlab.ui.control.RadioButton
        Button_9                        matlab.ui.control.RadioButton
        EditField                       matlab.ui.control.NumericEditField
        Label_3                         matlab.ui.control.Label
        rotateAxes                      matlab.ui.container.ButtonGroup
        rotzBtn                         matlab.ui.control.RadioButton
        rotyBtn                         matlab.ui.control.RadioButton
        rotxBtn                         matlab.ui.control.RadioButton
        PostureControlPanel             matlab.ui.container.Panel
        DoneTrimBtn                     matlab.ui.control.Button
        Panel_6                         matlab.ui.container.Panel
        OKButton                        matlab.ui.control.Button
        ResetPostureButton              matlab.ui.control.Button
        TrimmerRangeDown                matlab.ui.control.NumericEditField
        Label_2                         matlab.ui.control.Label
        TrimmerRangeUp                  matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        TrimDirectionSelectButtonGroup  matlab.ui.container.ButtonGroup
        zButton_2                       matlab.ui.control.RadioButton
        yButton_2                       matlab.ui.control.RadioButton
        xButton_2                       matlab.ui.control.RadioButton
        fixLegAndHoofTipDirectionBtn    matlab.ui.control.Button
        PCATrimmerAppButton             matlab.ui.control.Button
        CalculateTab                    matlab.ui.container.Tab
        Panel_5                         matlab.ui.container.Panel
        EditField_4                     matlab.ui.control.NumericEditField
        Label_6                         matlab.ui.control.Label
        EditField_3                     matlab.ui.control.NumericEditField
        Label_5                         matlab.ui.control.Label
        EditField_2                     matlab.ui.control.NumericEditField
        Label_4                         matlab.ui.control.Label
        TEditField                      matlab.ui.control.NumericEditField
        TEditFieldLabel                 matlab.ui.control.Label
        CalculationDirectionAxes        matlab.ui.container.ButtonGroup
        zButton                         matlab.ui.control.RadioButton
        yButton                         matlab.ui.control.RadioButton
        xButton                         matlab.ui.control.RadioButton
        CalManualyPanel                 matlab.ui.container.Panel
        ButtonGroup_4                   matlab.ui.container.ButtonGroup
        Button_14                       matlab.ui.control.RadioButton
        Button_13                       matlab.ui.control.RadioButton
        ROISelectionPanel               matlab.ui.container.Panel
        ResetCalAutoButton_2            matlab.ui.control.Button
        Button_8                        matlab.ui.control.Button
        Button_7                        matlab.ui.control.Button
        ROIButton                       matlab.ui.control.Button
        OnePointSelection               matlab.ui.container.Panel
        getPositionBtn                  matlab.ui.control.Button
        ZEditField                      matlab.ui.control.NumericEditField
        ZEditFieldLabel                 matlab.ui.control.Label
        YEditField                      matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        XEditField                      matlab.ui.control.NumericEditField
        XEditFieldLabel                 matlab.ui.control.Label
        ReChooseBtn                     matlab.ui.control.Button
        CalculationManualy              matlab.ui.control.Button
        AutoOrManualCal                 matlab.ui.container.ButtonGroup
        ManualCal                       matlab.ui.control.RadioButton
        AutoCal                         matlab.ui.control.RadioButton
        AutoCalPanel                    matlab.ui.container.Panel
        ResetCalAutoButton              matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        CaluculationAutoBtn             matlab.ui.control.Button
        ResetAllButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        ptCloud % Original Data
        importedfig % 
        newPoint    %　位置合わせ中の点群データ変数
        fixedPoint % 位置合わせ完了した時の点群データ
        secondFig   %　
        cutWidth      %　切る幅
        cutAxes
        convexMatrix % 回転行列
        trimAxes
        trimmerRangeUp
        trimmerRangeDown
        trimedfig
        secondfig
        sel_X
        sel_Y
        sel_Z
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            close all;
            app.cutWidth = app.TEditField.Value;
            app.trimAxes = app.TrimDirectionSelectButtonGroup.SelectedObject.Text;
            app.cutAxes = app.CalculationDirectionAxes.SelectedObject.Text;

            app.ManualPosturePanel.Enable = "off";
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            filename = uigetfile({'*.stl';'*.ply'});
            DoShow = false;

            app.ptCloud = import3Dmodel(filename, DoShow);
            app.filenameField.Value = filename;
            
             % インポート状態での主成分分析、軸描写
%             [app.importedfig, coeff, app.fixedPoint] = drawPCaxis3D(app.ptCloud.Location);
            [coeff, app.fixedPoint] = pca(app.ptCloud.Location);
            app.convexMatrix = coeff;
            % 新点群での主成分分析、軸修正、描写
            app.secondFig = scorePlot(app.fixedPoint);
            hold off;

        end

        % Button pushed function: PCATrimmerAppButton
        function PCATrimmerAppButtonPushed(app, event)
            close all;
            Point = app.fixedPoint;
            up = app.trimmerRangeUp;
            down = app.trimmerRangeDown;
            switch app.trimAxes 
                case 'x'
                    Point = Point(Point(:,1) > down & up > Point(:,1), :);
                case 'y'
                    Point = Point(Point(:,2) > down & up > Point(:,2), :);
                case 'z'
                    Point = Point(Point(:,3) > down & up > Point(:,3), :);
            end

            [app.trimedfig, coeff, app.fixedPoint] = drawPCaxis3D(Point); 
            app.trimedfig = scorePlot(app.fixedPoint);
            app.convexMatrix = app.convexMatrix * coeff;
            rotateHoof = app.ptCloud.Location * app.convexMatrix;
            figure("Name","Fix with PCA");
            pcshow(rotateHoof, "AxesVisibility","on");
        end

        % Value changed function: TrimmerRangeUp
        function TrimmerRangeUpValueChanged(app, event)
            value = app.TrimmerRangeUp.Value;
            app.trimmerRangeUp = value;
        end

        % Value changed function: TrimmerRangeDown
        function TrimmerRangeDownValueChanged(app, event)
            value = app.TrimmerRangeDown.Value;
            app.trimmerRangeDown = value;
        end

        % Selection changed function: TrimDirectionSelectButtonGroup
        function TrimDirectionSelectButtonGroupSelectionChanged(app, event)
            selectedButton = app.TrimDirectionSelectButtonGroup.SelectedObject;
            app.trimAxes = selectedButton.Text;
        end

        % Button pushed function: fixLegAndHoofTipDirectionBtn
        function fixLegAndHoofTipDirectionBtnPushed(app, event)
            tipWidth = 40;
            sp = app.fixedPoint;
            disp(sp);
            sPoint= fixLegsDirection(pointCloud(sp), app.trimAxes, app.cutWidth);
            app.fixedPoint = fixHoofTipDirection(sPoint, app.trimAxes, tipWidth, 2);
%             pcshowWithAxes(app.fixedPoint);
        end

        % Value changed function: TEditField
        function TEditFieldValueChanged(app, event)
            value = app.TEditField.Value;
            app.cutWidth = value;
        end

        % Selection changed function: CalculationDirectionAxes
        function CalculationDirectionAxesSelectionChanged(app, event)
            selectedButton = app.CalculationDirectionAxes.SelectedObject;
            app.cutAxes = selectedButton.Text;
        end

        % Button pushed function: CaluculationAutoBtn
        function CaluculationAutoBtnPushed(app, event)
            sPoint = app.fixedPoint;
            axes = app.cutAxes;
            t = app.cutWidth;
            [XData, PointSetX] = sliceData(sPoint, 'x', t);
            [YData, PointSetY] = sliceData(sPoint, 'y', t);
            [ZData, PointSetZ] = sliceData(sPoint, 'z', t);
            switch axes
                case 'x'
                    forcusData = XData;
                    forcusPoints = PointSetX;
                case 'y'
                    forcusData = YData;
                    forcusPoints = PointSetY;
                case 'z'
                    forcusData = ZData;
                    forcusPoints = PointSetZ;
            end
%             ax = app.UIAxes; 
%             subplot(2, 1, 1, ax);
%             plot(ax, forcusData(:,1), forcusData(:,2), 'b');
%             xlabel("dt-x");ylabel("周囲長")
%             subplot(2, 1, 2, ax);
%             plot(ax, forcusData(:,1), forcusData(:,3), 'r');
%             xlabel("dt-x");ylabel("Area");
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            close all;
%             newP = app.ptCloud.Location * app.convexMatrix;
            sp = app.fixedPoint;
%             app.newPoint = pointCloud(newP);
            app.secondfig = figure("Name","new Point View");
            hold on;
            pcshow(sp, 'AxesVisibility','on');
            hold off;
        end

        % Button pushed function: ResetAllButton
        function ResetAllButtonPushed(app, event)
%             app.PostureControlPanel.Enable = true;
              app.fixedPoint = '';
              app.filenameField.Value = '';
              app.newPoint = '';
              close all;
              app.ptCloud = '';
        end

        % Selection changed function: AutoOrManual
        function AutoOrManualSelectionChanged(app, event)
            selectedButton = app.AutoOrManual.SelectedObject;
            switch selectedButton.Text
                case '自動'
                    app.PostureControlPanel.Enable = "on";
                    app.ManualPosturePanel.Enable = "off";
                case '手動'
                    app.PostureControlPanel.Enable = "off";
                    app.ManualPosturePanel.Enable = "on";
            end
        end

        % Button pushed function: getPositionBtn
        function getPositionBtnPushed(app, event)
            try
                [x, y, z] = getDatatip(app.secondfig);
            catch 
                message = {'副蹄部の点群データチップを選んで'};
                uialert(app.UIFigure, message, 'Choose Nothing');
                return;
            end
            [app.sel_X, app.sel_Y, app.sel_Z] = makeDatatipToDouble(x, y, z); 
%             disp(app.dt_X); disp(app.dt_Y); disp(app.dt_Z);
            app.XEditField.Value = app.sel_X;
            app.YEditField.Value = app.sel_Y;
            app.ZEditField.Value = app.sel_Z;
        end

        % Button pushed function: ReChooseBtn
        function ReChooseBtnButtonPushed(app, event)
            app.XEditField.Value = 0;
            app.YEditField.Value = 0;
            app.ZEditField.Value = 0;
            app.sel_X = 0;app.sel_Y = 0;app.sel_Z = 0;
        end

        % Button pushed function: DoneTrimBtn
        function DoneTrimBtnButtonPushed(app, event)
            close all;
            donePCApt = app.ptCloud.Location * app.convexMatrix;
            app.fixedPoint = donePCApt;
            pcshowWithAxes(pointCloud(app.fixedPoint));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ResetAllButton = uibutton(app.UIFigure, 'push');
            app.ResetAllButton.ButtonPushedFcn = createCallbackFcn(app, @ResetAllButtonPushed, true);
            app.ResetAllButton.Text = 'Reset';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PostureTab = uitab(app.TabGroup);
            app.PostureTab.Title = '姿勢制御';
            app.PostureControlPanel = uipanel(app.PostureTab);
            app.PostureControlPanel.Title = '姿勢制御自動';
            app.PCATrimmerAppButton = uibutton(app.PostureControlPanel, 'push');
            app.PCATrimmerAppButton.ButtonPushedFcn = createCallbackFcn(app, @PCATrimmerAppButtonPushed, true);
            app.PCATrimmerAppButton.Text = 'トリミング';
            app.fixLegAndHoofTipDirectionBtn = uibutton(app.PostureControlPanel, 'push');
            app.fixLegAndHoofTipDirectionBtn.ButtonPushedFcn = createCallbackFcn(app, @fixLegAndHoofTipDirectionBtnPushed, true);
            app.fixLegAndHoofTipDirectionBtn.Text = '肢、蹄方向の修正';
            app.TrimDirectionSelectButtonGroup = uibuttongroup(app.PostureControlPanel);
            app.TrimDirectionSelectButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TrimDirectionSelectButtonGroupSelectionChanged, true);
            app.TrimDirectionSelectButtonGroup.Title = 'メイン軸';
            app.xButton_2 = uiradiobutton(app.TrimDirectionSelectButtonGroup);
            app.xButton_2.Text = 'x';
            app.xButton_2.Value = true;
            app.yButton_2 = uiradiobutton(app.TrimDirectionSelectButtonGroup);
            app.yButton_2.Text = 'y';
            app.zButton_2 = uiradiobutton(app.TrimDirectionSelectButtonGroup);
            app.zButton_2.Text = 'z';
            app.Label = uilabel(app.PostureControlPanel);
            app.Label.Text = '上端';
            app.TrimmerRangeUp = uieditfield(app.PostureControlPanel, 'numeric');
            app.TrimmerRangeUp.ValueChangedFcn = createCallbackFcn(app, @TrimmerRangeUpValueChanged, true);
            app.Label_2 = uilabel(app.PostureControlPanel);
            app.Label_2.Text = '下端';
            app.TrimmerRangeDown = uieditfield(app.PostureControlPanel, 'numeric');
            app.TrimmerRangeDown.ValueChangedFcn = createCallbackFcn(app, @TrimmerRangeDownValueChanged, true);
            app.ResetPostureButton = uibutton(app.PostureControlPanel, 'push');
            app.ResetPostureButton.Text = 'Reset';
            app.OKButton = uibutton(app.PostureControlPanel, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.Panel_6 = uipanel(app.PostureControlPanel);
            app.Panel_6.Title = 'Panel';
            app.DoneTrimBtn = uibutton(app.PostureControlPanel, 'push');
            app.DoneTrimBtn.ButtonPushedFcn = createCallbackFcn(app, @DoneTrimBtnButtonPushed, true);
            app.DoneTrimBtn.Text = 'トリミング完了';
            app.ManualPosturePanel = uipanel(app.PostureTab);
            app.ManualPosturePanel.Title = '手動';
            app.rotateAxes = uibuttongroup(app.ManualPosturePanel);
            app.rotateAxes.Title = '回転軸';
            app.rotxBtn = uiradiobutton(app.rotateAxes);
            app.rotxBtn.Text = 'x';
            app.rotxBtn.Value = true;
            app.rotyBtn = uiradiobutton(app.rotateAxes);
            app.rotyBtn.Text = 'y';
            app.rotzBtn = uiradiobutton(app.rotateAxes);
            app.rotzBtn.Text = 'z';
            app.Label_3 = uilabel(app.ManualPosturePanel);
            app.Label_3.Text = '回転角';
            app.EditField = uieditfield(app.ManualPosturePanel, 'numeric');
            app.ButtonGroup_3 = uibuttongroup(app.ManualPosturePanel);
            app.ButtonGroup_3.Title = 'データ選択';
            app.Button_9 = uiradiobutton(app.ButtonGroup_3);
            app.Button_9.Text = 'オリジナル';
            app.Button_9.Value = true;
            app.PCAButton = uiradiobutton(app.ButtonGroup_3);
            app.PCAButton.Text = 'PCA済み';
            app.Button_10 = uiradiobutton(app.ButtonGroup_3);
            app.Button_10.Text = '肢方向修正済み';
            app.Button_11 = uibutton(app.ManualPosturePanel, 'push');
            app.Button_11.Text = '回転';
            app.Knob = uiknob(app.ManualPosturePanel, 'continuous');
            app.Knob.Limits = [0 180];
            app.Knob.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100 110 120 130 140 150 160 170 180];
            app.Switch = uiswitch(app.ManualPosturePanel, 'slider');
            app.Switch.Items = {'マイナス方向', 'プラス方向'};
            app.Switch.Value = 'マイナス方向';
            app.OKButton_2 = uibutton(app.ManualPosturePanel, 'push');
            app.OKButton_2.Text = 'OK';
            app.AutoOrManual = uibuttongroup(app.PostureTab);
            app.AutoOrManual.SelectionChangedFcn = createCallbackFcn(app, @AutoOrManualSelectionChanged, true);
            app.Auto = uiradiobutton(app.AutoOrManual);
            app.Auto.Text = '自動';
            app.Auto.Value = true;
            app.Manual = uiradiobutton(app.AutoOrManual);
            app.Manual.Text = '手動';
            app.CalculateTab = uitab(app.TabGroup);
            app.CalculateTab.Title = '計算';
            app.UIAxes = uiaxes(app.CalculateTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.CalculateTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.AutoCalPanel = uipanel(app.CalculateTab);
            app.AutoCalPanel.Title = '周囲長計算・自動探索';
            app.CaluculationAutoBtn = uibutton(app.AutoCalPanel, 'push');
            app.CaluculationAutoBtn.ButtonPushedFcn = createCallbackFcn(app, @CaluculationAutoBtnPushed, true);
            app.CaluculationAutoBtn.Text = '自動計算';
            app.ResetCalAutoButton = uibutton(app.AutoCalPanel, 'push');
            app.ResetCalAutoButton.Text = 'Reset';
            app.AutoOrManualCal = uibuttongroup(app.CalculateTab);
            app.AutoCal = uiradiobutton(app.AutoOrManualCal);
            app.AutoCal.Text = '自動';
            app.AutoCal.Value = true;
            app.ManualCal = uiradiobutton(app.AutoOrManualCal);
            app.ManualCal.Text = '手動';
            app.CalManualyPanel = uipanel(app.CalculateTab);
            app.CalManualyPanel.Title = '周囲長計算・手動探索';
            app.CalculationManualy = uibutton(app.CalManualyPanel, 'push');
            app.CalculationManualy.Text = '計算';
            app.OnePointSelection = uipanel(app.CalManualyPanel);
            app.OnePointSelection.Title = '一点指定';
            app.ReChooseBtn = uibutton(app.OnePointSelection, 'push');
            app.ReChooseBtn.ButtonPushedFcn = createCallbackFcn(app, @ReChooseBtnButtonPushed, true);
            app.ReChooseBtn.Text = '再選択';
            app.XEditFieldLabel = uilabel(app.OnePointSelection);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.OnePointSelection, 'numeric');
            app.YEditFieldLabel = uilabel(app.OnePointSelection);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.OnePointSelection, 'numeric');
            app.ZEditFieldLabel = uilabel(app.OnePointSelection);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.OnePointSelection, 'numeric');
            app.getPositionBtn = uibutton(app.OnePointSelection, 'push');
            app.getPositionBtn.ButtonPushedFcn = createCallbackFcn(app, @getPositionBtnPushed, true);
            app.getPositionBtn.Text = '座標選択';
            app.ROISelectionPanel = uipanel(app.CalManualyPanel);
            app.ROISelectionPanel.Title = '範囲選択';
            app.ROIButton = uibutton(app.ROISelectionPanel, 'push');
            app.ROIButton.Text = 'ROIセット';
            app.Button_7 = uibutton(app.ROISelectionPanel, 'push');
            app.Button_7.Text = '切り出し';
            app.Button_8 = uibutton(app.ROISelectionPanel, 'push');
            app.Button_8.Text = '範囲選択';
            app.ResetCalAutoButton_2 = uibutton(app.ROISelectionPanel, 'push');
            app.ResetCalAutoButton_2.Text = 'Reset';
            app.ButtonGroup_4 = uibuttongroup(app.CalManualyPanel);
            app.Button_13 = uiradiobutton(app.ButtonGroup_4);
            app.Button_13.Text = '一点指定';
            app.Button_13.Value = true;
            app.Button_14 = uiradiobutton(app.ButtonGroup_4);
            app.Button_14.Text = '領域選択';
            app.CalculationDirectionAxes = uibuttongroup(app.CalculateTab);
            app.CalculationDirectionAxes.SelectionChangedFcn = createCallbackFcn(app, @CalculationDirectionAxesSelectionChanged, true);
            app.CalculationDirectionAxes.Title = 'メイン計算方向軸';
            app.xButton = uiradiobutton(app.CalculationDirectionAxes);
            app.xButton.Text = 'x';
            app.xButton.Value = true;
            app.yButton = uiradiobutton(app.CalculationDirectionAxes);
            app.yButton.Text = 'y';
            app.zButton = uiradiobutton(app.CalculationDirectionAxes);
            app.zButton.Text = 'z';
            app.TEditFieldLabel = uilabel(app.CalculateTab);
            app.TEditFieldLabel.Text = 'カット幅T';
            app.TEditField = uieditfield(app.CalculateTab, 'numeric');
            app.TEditField.ValueChangedFcn = createCallbackFcn(app, @TEditFieldValueChanged, true);
            app.TEditField.Value = 0.5;
            app.Panel_5 = uipanel(app.CalculateTab);
            app.Panel_5.Title = '結果';
            app.Label_4 = uilabel(app.Panel_5);
            app.Label_4.Text = '推定周囲長';
            app.EditField_2 = uieditfield(app.Panel_5, 'numeric');
            app.Label_5 = uilabel(app.Panel_5);
            app.Label_5.Text = '球節周囲長位置';
            app.EditField_3 = uieditfield(app.Panel_5, 'numeric');
            app.Label_6 = uilabel(app.Panel_5);
            app.Label_6.Text = '推定蹄背壁長';
            app.EditField_4 = uieditfield(app.Panel_5, 'numeric');
            app.DataManegementPanel = uipanel(app.UIFigure);
            app.DataManegementPanel.Title = 'データ管理';
            app.ImportButton = uibutton(app.DataManegementPanel, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import ';
            app.filenameField = uieditfield(app.DataManegementPanel, 'text');
            app.modelSave = uibutton(app.DataManegementPanel, 'push');
            app.modelSave.Text = 'モデル保存';
            app.SaveName = uieditfield(app.DataManegementPanel, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end